classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label_2                         matlab.ui.control.Label
        OpticcuplocatorLabel            matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        GlaucomaClassificationAppLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        GradCAMXAIanalysisLabel         matlab.ui.control.Label
        OriginalDFILabel                matlab.ui.control.Label
        UploadDFIhereButton             matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        AI_Model 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label_2.Text = 'Loading Model...';
    drawnow;
    
    % Check if the file exists to prevent crashing
    if isfile('glaucoma_model.mat')
        % Load the model from the file
        loadedData = load('glaucoma_model.mat', 'trainedNet');
        
        % Save it into the app's permanent memory!
        app.AI_Model = loadedData.trainedNet;
        
        app.Label_2.Text = 'Ready';
    else
        % Throw an error if the file is missing
        uialert(app.UIFigure, 'Error: Cannot find glaucoma_model.mat in the current folder.', 'Initialization Failed');
        app.Label_2.Text = 'Error: Model missing.';
    end
        end

        % Button pushed function: UploadDFIhereButton
        function UploadDFIhereButtonPushed(app, event)

% 1. Open a file chooser for the doctor
[file, path] = uigetfile({'*.jpg;*.png;*.jpeg', 'Image Files'});
if isequal(file, 0)
    return; % Stop if they cancel
end
fullFileName = fullfile(path, file);

% 2. Read Original Image and show it in PANEL 1
img = imread(fullFileName);
app.Image.ImageSource = img; 
app.Label.Text = 'Locating optic cup...';
drawnow;

% --- ADVANCED OPTIC DISC TRACKER ---
[rows, cols, ~] = size(img);
grayImg = rgb2gray(img);
eyeMask = grayImg > 20; 

blurredImg = imgaussfilt(grayImg, 30);
blurredImg(~eyeMask) = 0; 

[~, maxIndex] = max(blurredImg(:));
[centerY, centerX] = ind2sub(size(blurredImg), maxIndex);

boxSize = round(cols * 0.40); 
halfBox = round(boxSize / 2);

top = max(1, centerY - halfBox);
bottom = min(rows, centerY + halfBox);
left = max(1, centerX - halfBox);
right = min(cols, centerX + halfBox);

imgCropped = img(top:bottom, left:right, :);
% -----------------------------------

% 3. Show the tightly cropped nerve in PANEL 2
app.Image2.ImageSource = imgCropped; 
app.Label.Text = 'Analyzing...';
drawnow;

% 4. Resize for the AI
imgResized = imresize(imgCropped, [224 224]);

% 5. Make Prediction using the pre-loaded memory!
[classIdx, scores] = classify(app.AI_Model, imgResized);
confidence = max(scores) * 100;

% 6. Generate Grad-CAM Heatmap 
featureLayer = 'activation_49_relu'; 
scoreMap = gradCAM(app.AI_Model, imgResized, classIdx, 'FeatureLayer', featureLayer);

% Normalize the heatmap so colors pop
scoreMap = scoreMap - min(scoreMap(:)); 
scoreMap = scoreMap / max(scoreMap(:));

% 7. Create the Overlay 
scoreMapResized = imresize(scoreMap, [224 224]);
cmap = jet(255);
heatmapRGB = ind2rgb(uint8(scoreMapResized * 255), cmap);

% Blend cropped image with heatmap 
imgDouble = im2double(imgResized);
blended = (0.5) * imgDouble + (0.5) * heatmapRGB;
overlayImage = uint8(blended * 255);

% 8. Show the final XAI visualization in PANEL 3
app.Image3.ImageSource = overlayImage; 
app.Label.Text = sprintf('Diagnosis: %s (Confidence: %.1f%%)', string(classIdx), confidence);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.UploadDFIhereButton = uibutton(app.UIFigure, 'push');
            app.UploadDFIhereButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDFIhereButtonPushed, true);
            app.UploadDFIhereButton.Text = 'Upload DFI here';
            app.OriginalDFILabel = uilabel(app.UIFigure);
            app.OriginalDFILabel.Text = 'Original DFI';
            app.GradCAMXAIanalysisLabel = uilabel(app.UIFigure);
            app.GradCAMXAIanalysisLabel.Text = 'Grad-CAM XAI analysis';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.GlaucomaClassificationAppLabel = uilabel(app.UIFigure);
            app.GlaucomaClassificationAppLabel.Text = 'Glaucoma Classification App';
            app.Image3 = uiimage(app.UIFigure);
            app.OpticcuplocatorLabel = uilabel(app.UIFigure);
            app.OpticcuplocatorLabel.Text = 'Optic cup locator';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end